classdef visualizeAppReg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ComparetwosetstoolsTab         matlab.ui.container.Tab
        Dataset1SliderLabel            matlab.ui.control.Label
        Dataset1Slider                 matlab.ui.control.Slider
        Dataset2SliderLabel            matlab.ui.control.Label
        Dataset2Slider                 matlab.ui.control.Slider
        Dataset1Slider_2Label          matlab.ui.control.Label
        Dataset1Slider_2               matlab.ui.control.Slider
        Dataset2Slider_2Label          matlab.ui.control.Label
        Dataset2Slider_2               matlab.ui.control.Slider
        MarkersizeSliderLabel          matlab.ui.control.Label
        MarkersizeSlider               matlab.ui.control.Slider
        NumberofneuronsLogarithmicscaleLabel  matlab.ui.control.Label
        AbsolutevalueofregressorLabel  matlab.ui.control.Label
        CheckdensitiesofbothsetstoolsTab  matlab.ui.container.Tab
        DensitytoplotforButtonGroup    matlab.ui.container.ButtonGroup
        Dataset1Button                 matlab.ui.control.RadioButton
        Dataset2Button                 matlab.ui.control.RadioButton
        GaussianizeSliderLabel         matlab.ui.control.Label
        GaussianizeSlider              matlab.ui.control.Slider
        Isovalue1SliderLabel           matlab.ui.control.Label
        Isovalue1Slider                matlab.ui.control.Slider
        Isovalue2SliderLabel           matlab.ui.control.Label
        Isovalue2Slider                matlab.ui.control.Slider
        Isovalue3SliderLabel           matlab.ui.control.Label
        Isovalue3Slider                matlab.ui.control.Slider
        DFFregressorplottoolsTab       matlab.ui.container.Tab
        LoadZBraingridobjectButton     matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ListofcommentsDropDownLabel    matlab.ui.control.Label
        ListofcommentsDropDown         matlab.ui.control.DropDown
        ListofkeywordsDropDownLabel    matlab.ui.control.Label
        ListofkeywordsDropDown         matlab.ui.control.DropDown
        Keywordsfordataset1usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset1usecommastoseparateEditField  matlab.ui.control.EditField
        Keywordsfordataset2usecommastoseparateEditFieldLabel  matlab.ui.control.Label
        Keywordsfordataset2usecommastoseparateEditField  matlab.ui.control.EditField
        PleasechoserepresentationoptionLabel  matlab.ui.control.Label
        PleasechoserepresentationoptionListBox  matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = private)
        
        % ZBraingrid objects, and related:
        zgrid % Data loaded through loading button, should be a ZBraingrid object
        zgridpath % Path to data, to be indicated in text zone
        
        % Information on the two subsets:
        zset1 % ZBraingrid object containing datasets selected with keywords 1
        zset2 % ZBraingrid object containing datasets selected with keywords 2
        nneu1 % Number of neurons for dataset 1
        nneu2 % Number of neurons for dataset 2
        zcobj1 % Sorted correlations vector for dataset 1
        zcobj2 % Sorted correlations vector for dataset 2
        maval1 % Maximum regressor absolute value for dataset 1
        maval2 % Maximum regressor absolute value for dataset 2
        
        % Plot options:
        ptool % Tool for plot, can be 1, 2, or 3
        mrksize % Marker size for plot options 1 and 3
        arotate % To rotate figure
        azoom % To zoom in figure
        aview % Rotation of plot
        alim % Limits for the axes
        
        % Plot options for isovalues:
        whichset % Which set to plot density
        gauss % Gaussianization parameter
        isoval1 % First isovalue
        isoval2 % Second isovalue
        isoval3 % Third isovalue
        
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function whichPlot(app)
            
            if app.ptool == 1
                automaticPlot1(app);
            elseif app.ptool == 2
                automaticPlot2(app);
            elseif app.ptool == 3
                automaticPlot3(app);
            end
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function updateIso(app)
            
            % Get set value from whichset:
            if app.whichset == 1
                zcobj = app.zcobj1;
            elseif app.whichset == 2
                zcobj = app.zcobj2;
            end
            
            % Update isovalue sliders:
            app.Isovalue1Slider.Limits = [zcobj(end), zcobj(round(length(zcobj)/10))];
            %app.Isovalue1Slider.Value = zcobj(round(1*length(zcobj)/5));
            app.Isovalue2Slider.Limits = [zcobj(end), zcobj(round(length(zcobj)/10))];
            %app.Isovalue2Slider.Value = zcobj(round(2*length(zcobj)/5));
            app.Isovalue3Slider.Limits = [zcobj(end), zcobj(round(length(zcobj)/10))];
            %app.Isovalue3Slider.Value = zcobj(round(3*length(zcobj)/5));            
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%        automaticPlot1(app);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        automaticPlot2(app);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%        automaticPlot3(app);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add path to class:
            addpath(genpath('/home/ljp/Science/Hippolyte/multiSensorint'))
            
            % Initialize some values:
            app.arotate = 0;
            app.azoom = 0;
            app.aview = [-45, 45];
            app.alim = [0.496, 1.122, 0.276];
            app.mrksize = 5;
            app.whichset = 1;
            
            % Plotting parameters:
            app.UIAxes.DataAspectRatio = [1, 1, 1];
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            title(app.UIAxes, 'Binary comparison between two subsets: common in black, green for 1st, purple for 2nd')
            xlabel(app.UIAxes, 'x-axis', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'y-axis', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'z-axis', 'Interpreter', 'latex')
            app.UIAxes.XLim = [0, app.alim(1)];
            app.UIAxes.YLim = [0, app.alim(2)];
            app.UIAxes.ZLim = [0, app.alim(3)];
            view(app.UIAxes, app.aview)
        end

        % Button pushed function: LoadZBraingridobjectButton
        function LoadZBraingridobjectButtonPushed(app, event)
            [file, path] = uigetfile;
            if ~endsWith(file, ".mat")
                msgbox('Imported data should be a .mat file.')
            elseif file ~= 0
                % Rebuild path:
                filepath = fullfile(path, file);
                % Get class:
                temp = load(char(filepath));
                field_temp = fields(temp);
                temp = temp.(field_temp{1});
                % Class should be ZBraingrid
                if class(temp) == "ZBraingrid"
                    % Private properties:
                    app.zgrid = temp;
                    app.zgridpath = filepath;
                    app.TextArea.Value = {filepath};
                    % Updating comments and keywords:
                    zgridlen = length(temp.comments);
                    unique_key = "";
                    ListofcommentsDropDown_temp = cell(1, zgridlen);
                    for i = 1:zgridlen
                        unique_key = unique_key + temp.comments(i);
                        if i ~= zgridlen
                            unique_key = unique_key + " + ";
                        end
                        ListofcommentsDropDown_temp{i} = char(temp.comments(i));
                    end
                    app.ListofcommentsDropDown.Items = ListofcommentsDropDown_temp;
                    unique_key = split(unique_key, " + ");
                    unique_key = unique(unique_key);
                    ListofkeywordsDropDown_temp = cell(1, length(unique_key));
                    for i = 1:length(unique_key)
                        ListofkeywordsDropDown_temp{i} = char(unique_key(i));
                    end
                    app.ListofkeywordsDropDown.Items = ListofkeywordsDropDown_temp;
                else
                    msgbox('Imported data should be a ZBraingrid object.')
                end
            end
        end

        % Value changed function: 
        % Keywordsfordataset1usecommastoseparateEditField
        function Keywordsfordataset1usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset1usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset1 = app.zgrid;
            for i = 1:length(value)
                try
                    % Define dataset:
                    app.zset1 = subset(app.zset1, value(i));            
                catch
                    msgbox('Subset to get is empty.')
                    app.Keywordsfordataset1usecommastoseparateEditField.Value = '';
                end
            end
            % Adapt sliders:
            obj1 = flatten(app.zset1);
            % Adapt number of neurons slider:
            app.Dataset1Slider.Limits = [log10(1), log10(length(obj1.Zindex))];
            app.nneu1 = floor(length(obj1.Zindex) / 1000);
            app.Dataset1Slider.Value = log10(app.nneu1);
            % Adapt absolut regressor value slider:
            app.zcobj1 = sort(abs(obj1.Zcorrel), 'descend');
            app.Dataset1Slider_2.Limits = [0, app.zcobj1(1)];
            app.maval1 = app.zcobj1(app.nneu1); 
            app.Dataset1Slider_2.Value = app.maval1;    
            
            % Updating isovalues:
            if app.whichset == 1
                updateIso(app)
            end
        end

        % Value changed function: 
        % Keywordsfordataset2usecommastoseparateEditField
        function Keywordsfordataset2usecommastoseparateEditFieldValueChanged(app, event)
            value = app.Keywordsfordataset2usecommastoseparateEditField.Value;
            % Spliting to find keywords:
            value = split(value, [" , ", " ,", ", ", ","]);
            % Looping to reduce ZBraingrid object:
            app.zset2 = app.zgrid;
            for i = 1:length(value)
                try
                    % Define dataset:
                    app.zset2 = subset(app.zset2, value(i));  
                catch
                    msgbox('Subset to get is empty.')
                    app.Keywordsfordataset2usecommastoseparateEditField.Value = '';
                end
            end        
            % Adapt sliders:
            obj2 = flatten(app.zset2);
            % Adapt number of neurons slider:
            app.Dataset2Slider.Limits = [log10(1), log10(length(obj2.Zindex))];
            app.nneu2 = floor(length(obj2.Zindex) / 1000);
            app.Dataset2Slider.Value = log10(app.nneu2);
            % Adapt absolut regressor value slider:
            app.zcobj2 = sort(abs(obj2.Zcorrel), 'descend');
            app.Dataset2Slider_2.Limits = [0, app.zcobj2(1)];
            app.maval2 = app.zcobj2(app.nneu2); 
            app.Dataset2Slider_2.Value = app.maval2; 
            
            % Updating isovalues:
            if app.whichset == 2
                updateIso(app)
            end
        end

        % Value changed function: 
        % PleasechoserepresentationoptionListBox
        function PleasechoserepresentationoptionListBoxValueChanged(app, event)
            value = app.PleasechoserepresentationoptionListBox.Value;
            % Replace app.ptool with selected option:
            possible_values = app.PleasechoserepresentationoptionListBox.Items;
            for i = 1:length(possible_values)
                if value == string(app.PleasechoserepresentationoptionListBox.Items{i})
                    app.ptool = i;
                    break
                end
            end
        end

        % Value changed function: Dataset1Slider
        function Dataset1SliderValueChanged(app, event)
            value = app.Dataset1Slider.Value;
            % Change number of neurons and absolute regressor value:
            app.nneu1 = round(10^value);
            app.maval1 = app.zcobj1(app.nneu1);
            app.Dataset1Slider_2.Value = app.maval1;
        end

        % Value changed function: Dataset2Slider
        function Dataset2SliderValueChanged(app, event)
            value = app.Dataset2Slider.Value;
            % Change number of neurons and absolute regressor value:
            app.nneu2 = round(10^value);
            app.maval2 = app.zcobj2(app.nneu2);      
            app.Dataset2Slider_2.Value = app.maval2;      
        end

        % Value changed function: Dataset1Slider_2
        function Dataset1Slider_2ValueChanged(app, event)
            value = app.Dataset1Slider_2.Value;
            % Change number of neurons and absolute regressor value:
            app.maval1 = value;
            app.nneu1 = sum(app.zcobj1 >= app.maval1);
            if app.nneu1 == 0
                app.nneu1 = 1;
            end
            app.Dataset1Slider.Value = log10(app.nneu1);
        end

        % Value changed function: Dataset2Slider_2
        function Dataset2Slider_2ValueChanged(app, event)
            value = app.Dataset2Slider_2.Value;
            % Change number of neurons and absolute regressor value:
            app.maval2 = value;
            app.nneu2 = sum(app.zcobj2 >= app.maval2);
            if app.nneu2 == 0
                app.nneu2 = 1;
            end
            app.Dataset2Slider.Value = log10(app.nneu2);            
        end

        % Value changed function: MarkersizeSlider
        function MarkersizeSliderValueChanged(app, event)
            value = app.MarkersizeSlider.Value;
            % Change marker size:
            app.mrksize = value;
        end

        % Selection changed function: DensitytoplotforButtonGroup
        function DensitytoplotforButtonGroupSelectionChanged(app, event)
            selectedButton = app.DensitytoplotforButtonGroup.SelectedObject;
            % Change whichset property if needed:
            if app.whichset == 1 && selectedButton.text == "Dataset 2"
                app.whichset = 2;
                updateIso(app)
            elseif app.whichset == 2 && selectedButton.text == "Dataset 1"
                app.whichset = 1;
                updateIso(app)
            end
        end

        % Value changed function: GaussianizeSlider
        function GaussianizeSliderValueChanged(app, event)
            value = app.GaussianizeSlider.Value;
            % Change gaussianity:
            app.gauss = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComparetwosetstoolsTab = uitab(app.TabGroup);
            app.ComparetwosetstoolsTab.Title = 'Compare two sets - tools';
            app.Dataset1SliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset1SliderLabel.Text = 'Dataset 1';
            app.Dataset1Slider = uislider(app.ComparetwosetstoolsTab);
            app.Dataset1Slider.Limits = [0 1];
            app.Dataset1Slider.ValueChangedFcn = createCallbackFcn(app, @Dataset1SliderValueChanged, true);
            app.Dataset2SliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset2SliderLabel.Text = 'Dataset 2';
            app.Dataset2Slider = uislider(app.ComparetwosetstoolsTab);
            app.Dataset2Slider.Limits = [0 1];
            app.Dataset2Slider.ValueChangedFcn = createCallbackFcn(app, @Dataset2SliderValueChanged, true);
            app.Dataset1Slider_2Label = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset1Slider_2Label.Text = 'Dataset 1';
            app.Dataset1Slider_2 = uislider(app.ComparetwosetstoolsTab);
            app.Dataset1Slider_2.Limits = [0 1];
            app.Dataset1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Dataset1Slider_2ValueChanged, true);
            app.Dataset2Slider_2Label = uilabel(app.ComparetwosetstoolsTab);
            app.Dataset2Slider_2Label.Text = 'Dataset2';
            app.Dataset2Slider_2 = uislider(app.ComparetwosetstoolsTab);
            app.Dataset2Slider_2.Limits = [0 1];
            app.Dataset2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Dataset2Slider_2ValueChanged, true);
            app.MarkersizeSliderLabel = uilabel(app.ComparetwosetstoolsTab);
            app.MarkersizeSliderLabel.Text = 'Markersize';
            app.MarkersizeSlider = uislider(app.ComparetwosetstoolsTab);
            app.MarkersizeSlider.Limits = [0 30];
            app.MarkersizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkersizeSliderValueChanged, true);
            app.MarkersizeSlider.Value = 5;
            app.NumberofneuronsLogarithmicscaleLabel = uilabel(app.ComparetwosetstoolsTab);
            app.NumberofneuronsLogarithmicscaleLabel.Text = {'Number of neurons'; '(Logarithmic scale)'};
            app.AbsolutevalueofregressorLabel = uilabel(app.ComparetwosetstoolsTab);
            app.AbsolutevalueofregressorLabel.Text = 'Absolute value of regressor';
            app.CheckdensitiesofbothsetstoolsTab = uitab(app.TabGroup);
            app.CheckdensitiesofbothsetstoolsTab.Title = 'Check densities of both sets - tools';
            app.DensitytoplotforButtonGroup = uibuttongroup(app.CheckdensitiesofbothsetstoolsTab);
            app.DensitytoplotforButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DensitytoplotforButtonGroupSelectionChanged, true);
            app.DensitytoplotforButtonGroup.Title = 'Density to plot for:';
            app.Dataset1Button = uiradiobutton(app.DensitytoplotforButtonGroup);
            app.Dataset1Button.Text = 'Dataset 1';
            app.Dataset1Button.Value = true;
            app.Dataset2Button = uiradiobutton(app.DensitytoplotforButtonGroup);
            app.Dataset2Button.Text = 'Dataset 2';
            app.GaussianizeSliderLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.GaussianizeSliderLabel.Text = 'Gaussianize';
            app.GaussianizeSlider = uislider(app.CheckdensitiesofbothsetstoolsTab);
            app.GaussianizeSlider.Limits = [0 0.03];
            app.GaussianizeSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianizeSliderValueChanged, true);
            app.Isovalue1SliderLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue1SliderLabel.Text = 'Isovalue 1';
            app.Isovalue1Slider = uislider(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue2SliderLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue2SliderLabel.Text = 'Isovalue 2';
            app.Isovalue2Slider = uislider(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue3SliderLabel = uilabel(app.CheckdensitiesofbothsetstoolsTab);
            app.Isovalue3SliderLabel.Text = 'Isovalue 3';
            app.Isovalue3Slider = uislider(app.CheckdensitiesofbothsetstoolsTab);
            app.DFFregressorplottoolsTab = uitab(app.TabGroup);
            app.DFFregressorplottoolsTab.Title = 'DFF & regressor plot - tools';
            app.LoadZBraingridobjectButton = uibutton(app.UIFigure, 'push');
            app.LoadZBraingridobjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadZBraingridobjectButtonPushed, true);
            app.LoadZBraingridobjectButton.Text = 'Load ZBraingrid object';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No ZBraingrid data provided yet'};
            app.ListofcommentsDropDownLabel = uilabel(app.UIFigure);
            app.ListofcommentsDropDownLabel.Text = 'List of comments';
            app.ListofcommentsDropDown = uidropdown(app.UIFigure);
            app.ListofcommentsDropDown.Items = {'No Zbraingrid data provided yet'};
            app.ListofcommentsDropDown.Value = 'No Zbraingrid data provided yet';
            app.ListofkeywordsDropDownLabel = uilabel(app.UIFigure);
            app.ListofkeywordsDropDownLabel.Text = 'List of keywords';
            app.ListofkeywordsDropDown = uidropdown(app.UIFigure);
            app.ListofkeywordsDropDown.Items = {'No ZBraingrid data provided yet'};
            app.ListofkeywordsDropDown.Value = 'No ZBraingrid data provided yet';
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset1usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 1'; '(use commas to separate) '};
            app.Keywordsfordataset1usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset1usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset1usecommastoseparateEditFieldValueChanged, true);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel = uilabel(app.UIFigure);
            app.Keywordsfordataset2usecommastoseparateEditFieldLabel.Text = {'Keywords for dataset 2'; '(use commas to separate)'};
            app.Keywordsfordataset2usecommastoseparateEditField = uieditfield(app.UIFigure, 'text');
            app.Keywordsfordataset2usecommastoseparateEditField.ValueChangedFcn = createCallbackFcn(app, @Keywordsfordataset2usecommastoseparateEditFieldValueChanged, true);
            app.PleasechoserepresentationoptionLabel = uilabel(app.UIFigure);
            app.PleasechoserepresentationoptionLabel.Text = 'Please chose representation option:';
            app.PleasechoserepresentationoptionListBox = uilistbox(app.UIFigure);
            app.PleasechoserepresentationoptionListBox.Items = {'Compare two sets', 'Check densities of both sets', 'DFF & regressor plot'};
            app.PleasechoserepresentationoptionListBox.ValueChangedFcn = createCallbackFcn(app, @PleasechoserepresentationoptionListBoxValueChanged, true);
            app.PleasechoserepresentationoptionListBox.Value = 'Compare two sets';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualizeAppReg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end